classdef MainWindow < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                        matlab.ui.Figure
    InspectingthesignalsButton      matlab.ui.control.Button
    LoadmultitiffandtracksfilesPanel  matlab.ui.container.Panel
    LoadtifButton                   matlab.ui.control.Button
    LoadtracksButton                matlab.ui.control.Button
    tiffLabel                       matlab.ui.control.Label
    tiffnameedit                    matlab.ui.control.EditField
    TracksEditField                 matlab.ui.control.EditField
    DoneLampLabel                   matlab.ui.control.Label
    DoneLamp                        matlab.ui.control.Lamp
    tiffLamp                        matlab.ui.control.Lamp
    CelldiameterpixelsLabel         matlab.ui.control.Label
    CellDiameterEditField           matlab.ui.control.NumericEditField
    ChannelsLabelsPanel             matlab.ui.container.Panel
    RedchannelnameEditFieldLabel    matlab.ui.control.Label
    RedchannelnameEditField         matlab.ui.control.EditField
    GreenchannelnameEditFieldLabel  matlab.ui.control.Label
    GreenchannelnameEditField       matlab.ui.control.EditField
    BluechannelnameEditFieldLabel   matlab.ui.control.Label
    BluechannelnameEditField        matlab.ui.control.EditField
    FucciPhaseAlghorithmButtonGroup  matlab.ui.container.ButtonGroup
    GemininPCNAPIPButton            matlab.ui.control.RadioButton
    Geminin_Cdt1Button              matlab.ui.control.RadioButton
    Image                           matlab.ui.control.Image
  end

  
  properties (Access = private)
    Multitiff % Multitiff matrix (example:  loadtiff('ExampleData/RGB-TIFF.tif')
    Tracks % .mat AllTracks structure
  end
  
  methods (Access = public)
    
    function set_tiff_and_tracks_from_outside(app,tiffName, tracksName)
      app.tiffnameedit.Value = tiffName;
      app.LoadtifButtonPushed()
      
      app.TracksEditField.Value = tracksName;
      app.LoadtracksButtonPushed()
    end
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app)
%       addpath('saveastiff_4_5/')
    end

    % Button pushed function: InspectingthesignalsButton
    function InspectingthesignalsButtonPushed(app, event)
      if ~isempty(app.Multitiff) && ~isempty(app.Tracks)
        TracksAuditorObject = TracksAuditorWindowController(app.Multitiff, app.Tracks);
        TracksAuditorObject.setChannelTitles({app.RedchannelnameEditField.Value,...
          app.GreenchannelnameEditField.Value,...
          app.BluechannelnameEditField.Value})
       TracksAuditorObject.setDefaultRectangleSize(app.CellDiameterEditField.Value)
       TracksAuditorObject.setFucciPhasePredictidMethod(...
         app.FucciPhaseAlghorithmButtonGroup.SelectedObject.Text)
        TracksAuditorObject.RunGUI();
       
      end
    end

    % Button pushed function: LoadtifButton
    function LoadtifButtonPushed(app, event)
      if isempty(app.tiffnameedit.Value)
        [filename, path, ~] = uigetfile('*.tif', 'Pick a .tif file');
        FullPathName = fullfile(path,filename);
      else
        FullPathName = app.tiffnameedit.Value;
      end
      
      if FullPathName
        app.tiffnameedit.Value = FullPathName;
        app.tiffLabel.Text = 'Loading...';
        app.tiffLamp.Color = [1 1 0];
        drawnow
        app.Multitiff = loadtiff(FullPathName);
        app.tiffLabel.Text = 'tiff OK';
        app.tiffLamp.Color = [0 1 0];
      end
    end

    % Button pushed function: LoadtracksButton
    function LoadtracksButtonPushed(app, event)
      if isempty(app.TracksEditField.Value)
        [filename, path, ~] = uigetfile('*.mat', 'Pick a .mat file');
        FullPathName = fullfile(path,filename);
      else
        FullPathName = app.TracksEditField.Value;
      end
      
      if FullPathName
        app.TracksEditField.Value = FullPathName;
        app.DoneLampLabel.Text = 'Loading...';
        app.DoneLamp.Color = [1 1 0];
        drawnow
        A = load(FullPathName);
        app.Tracks = A.AllTracks.Tracks; % Load table with each position
        app.DoneLampLabel.Text = 'tracks OK';
        app.DoneLamp.Color = [0 1 0];
      end
    end

    % Callback function
    function MouseCallbackButtonPushed(app, event)
      MovingRectangle_MouseCallbackRGB(app.Multitiff)
    end

    % Callback function
    function UsebluefornormalizationCheckBoxValueChanged(app, event)
      app.UseredfornormalizationCheckBox.Value = 0;
      app.UsegreenfornormalizationCheckBox.Value = 0;
      app.UsebluefornormalizationCheckBox.Value = 0;
      event.Source.Value = 1;
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.InspectingthesignalsButton = uibutton(app.UIFigure, 'push');
      app.InspectingthesignalsButton.ButtonPushedFcn = createCallbackFcn(app, @InspectingthesignalsButtonPushed, true);
      app.InspectingthesignalsButton.Text = {'Inspecting the signals'; ''};
      app.LoadmultitiffandtracksfilesPanel = uipanel(app.UIFigure);
      app.LoadmultitiffandtracksfilesPanel.Title = 'Load multitiff and tracks files:';
      app.LoadtifButton = uibutton(app.LoadmultitiffandtracksfilesPanel, 'push');
      app.LoadtifButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtifButtonPushed, true);
      app.LoadtifButton.Text = 'Load .tif';
      app.LoadtracksButton = uibutton(app.LoadmultitiffandtracksfilesPanel, 'push');
      app.LoadtracksButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtracksButtonPushed, true);
      app.LoadtracksButton.Text = 'Load tracks';
      app.tiffLabel = uilabel(app.LoadmultitiffandtracksfilesPanel);
      app.tiffLabel.Text = 'Done';
      app.tiffnameedit = uieditfield(app.LoadmultitiffandtracksfilesPanel, 'text');
      app.TracksEditField = uieditfield(app.LoadmultitiffandtracksfilesPanel, 'text');
      app.DoneLampLabel = uilabel(app.LoadmultitiffandtracksfilesPanel);
      app.DoneLampLabel.Text = 'Done';
      app.DoneLamp = uilamp(app.LoadmultitiffandtracksfilesPanel);
      app.tiffLamp = uilamp(app.LoadmultitiffandtracksfilesPanel);
      app.CelldiameterpixelsLabel = uilabel(app.LoadmultitiffandtracksfilesPanel);
      app.CelldiameterpixelsLabel.Text = ' Cell diameter  (pixels)';
      app.CellDiameterEditField = uieditfield(app.LoadmultitiffandtracksfilesPanel, 'numeric');
      app.CellDiameterEditField.Value = 23;
      app.ChannelsLabelsPanel = uipanel(app.UIFigure);
      app.ChannelsLabelsPanel.Title = 'Channels Labels: ';
      app.RedchannelnameEditFieldLabel = uilabel(app.ChannelsLabelsPanel);
      app.RedchannelnameEditFieldLabel.Text = 'Red channel name: ';
      app.RedchannelnameEditField = uieditfield(app.ChannelsLabelsPanel, 'text');
      app.RedchannelnameEditField.Value = 'Red';
      app.GreenchannelnameEditFieldLabel = uilabel(app.ChannelsLabelsPanel);
      app.GreenchannelnameEditFieldLabel.Text = 'Green channel name:';
      app.GreenchannelnameEditField = uieditfield(app.ChannelsLabelsPanel, 'text');
      app.GreenchannelnameEditField.Value = 'Green';
      app.BluechannelnameEditFieldLabel = uilabel(app.ChannelsLabelsPanel);
      app.BluechannelnameEditFieldLabel.Text = 'Blue channel name:';
      app.BluechannelnameEditField = uieditfield(app.ChannelsLabelsPanel, 'text');
      app.BluechannelnameEditField.Value = 'Blue';
      app.FucciPhaseAlghorithmButtonGroup = uibuttongroup(app.UIFigure);
      app.FucciPhaseAlghorithmButtonGroup.Title = 'Fucci Phase Alghorithm';
      app.GemininPCNAPIPButton = uiradiobutton(app.FucciPhaseAlghorithmButtonGroup);
      app.GemininPCNAPIPButton.Text = 'Geminin-PCNA - PIP';
      app.GemininPCNAPIPButton.Value = true;
      app.Geminin_Cdt1Button = uiradiobutton(app.FucciPhaseAlghorithmButtonGroup);
      app.Geminin_Cdt1Button.Text = 'Geminin_Cdt1';
      app.Image = uiimage(app.FucciPhaseAlghorithmButtonGroup);
      app.Image.ImageSource = 'Methods.jpg';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = MainWindow

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @startupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end